classdef UI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        signal_generate                matlab.ui.control.Button
        AntennaconfigurationPanel      matlab.ui.container.Panel
        samplefrequencyLabel           matlab.ui.control.Label
        sample_frequence               matlab.ui.control.NumericEditField
        numberofchirpsEditFieldLabel   matlab.ui.control.Label
        number_of_chirps               matlab.ui.control.NumericEditField
        bandwidthEditFieldLabel        matlab.ui.control.Label
        band_width                     matlab.ui.control.NumericEditField
        numberofsamplesEditFieldLabel  matlab.ui.control.Label
        number_of_samples              matlab.ui.control.NumericEditField
        TxpositionsLabel               matlab.ui.control.Label
        Tx_positions                   matlab.ui.control.TextArea
        carrierfrequenceTextAreaLabel  matlab.ui.control.Label
        carrier_frequence              matlab.ui.control.TextArea
        RxpositionsLabel               matlab.ui.control.Label
        Rx_positionns                  matlab.ui.control.TextArea
        ObjectconfigrationPanel        matlab.ui.container.Panel
        varianceofnoiseLabel           matlab.ui.control.Label
        variance                       matlab.ui.control.NumericEditField
        rangesEditFieldLabel           matlab.ui.control.Label
        ranges                         matlab.ui.control.EditField
        speedsEditFieldLabel           matlab.ui.control.Label
        speeds                         matlab.ui.control.EditField
        DOATextAreaLabel               matlab.ui.control.Label
        DOA                            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        radarParameter;
        objectParameter;
        generated_signal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: signal_generate
        function signal_generateButtonPushed(app, event)
            % set radar parameters
            f0 = str2num(app.carrier_frequence.Value{1});
            B = app.band_width.Value;
            fs = app.sample_frequence.Value;
            N_chirp = app.number_of_chirps.Value;
            N_sample = app.number_of_samples.Value;
            Tx_P = str2num(app.Tx_positions.Value{1});
            Rx_P = str2num(app.Rx_positionns.Value{1});
            app.radarParameter = defineRadar(f0, B, fs, N_chirp, N_sample, Tx_P, Rx_P);
            
            % set object parameters
            r0 = str2num(app.ranges.Value);
            vr = str2num(app.speeds.Value);
            u = str2num(app.DOA.Value{1});
            sigma = app.variance.Value;
            num_Object = length(r0);
            % generate signal of first object
            app.objectParameter = defineObject(r0(1), vr(1), u(1,:), sigma);
            app.generated_signal = signalGenerator_SO(app.radarParameter, app.objectParameter);
            
            % generate signal of all the objects if there are
            if num_Object >= 2
                for i = 2: num_Object
                    app.objectParameter = defineObject(r0(i), vr(i), u(i,:), sigma);
                    app.generated_signal = app.generated_signal + signalGenerator_SO(app.radarParameter, app.objectParameter);
                end
            end
            %plot
            processed_signal = abs(fftshift(fft2(app.generated_signal, N_sample, N_chirp)));
            k = 0 : N_chirp - 1; % in which chirp
            ns = 0 : N_sample - 1;   % in which sample
            [X, Y] = meshgrid(k, ns);
            surf(app.UIAxes, X, Y, processed_signal(:, :, 1));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.signal_generate = uibutton(app.UIFigure, 'push');
            app.signal_generate.ButtonPushedFcn = createCallbackFcn(app, @signal_generateButtonPushed, true);
            app.signal_generate.Text = {'genrate'; ''};
            app.AntennaconfigurationPanel = uipanel(app.UIFigure);
            app.AntennaconfigurationPanel.Title = 'Antenna configuration';
            app.samplefrequencyLabel = uilabel(app.AntennaconfigurationPanel);
            app.samplefrequencyLabel.Text = 'sample frequency';
            app.sample_frequence = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.sample_frequence.ValueDisplayFormat = '%.2e';
            app.sample_frequence.Value = 10000000;
            app.numberofchirpsEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofchirpsEditFieldLabel.Text = 'number of chirps';
            app.number_of_chirps = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_chirps.ValueDisplayFormat = '%.0f';
            app.number_of_chirps.Value = 160;
            app.bandwidthEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.bandwidthEditFieldLabel.Text = 'bandwidth';
            app.band_width = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.band_width.ValueDisplayFormat = '%.2e';
            app.band_width.Value = 3000000000;
            app.numberofsamplesEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofsamplesEditFieldLabel.Text = 'number of samples';
            app.number_of_samples = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_samples.ValueDisplayFormat = '%.0f';
            app.number_of_samples.Value = 250;
            app.TxpositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.TxpositionsLabel.Text = 'Tx positions';
            app.Tx_positions = uitextarea(app.AntennaconfigurationPanel);
            app.Tx_positions.Value = {'[0, 0, 0; 1/2, 0, 0; 0, 1/2, 0; 1/2, 1/2, 0]'};
            app.carrierfrequenceTextAreaLabel = uilabel(app.AntennaconfigurationPanel);
            app.carrierfrequenceTextAreaLabel.Text = 'carrier frequence';
            app.carrier_frequence = uitextarea(app.AntennaconfigurationPanel);
            app.carrier_frequence.Value = {'70e9'};
            app.RxpositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.RxpositionsLabel.Text = 'Rx positions';
            app.Rx_positionns = uitextarea(app.AntennaconfigurationPanel);
            app.Rx_positionns.Value = {'[0, 0, 0; 1/2, 0, 0; 0, 1/2, 0; 1/2, 1/2, 0]'};
            app.ObjectconfigrationPanel = uipanel(app.UIFigure);
            app.ObjectconfigrationPanel.Title = 'Object configration';
            app.varianceofnoiseLabel = uilabel(app.ObjectconfigrationPanel);
            app.varianceofnoiseLabel.Text = {'variance'; 'of noise'};
            app.variance = uieditfield(app.ObjectconfigrationPanel, 'numeric');
            app.variance.Value = 6;
            app.rangesEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.rangesEditFieldLabel.Text = 'ranges';
            app.ranges = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.ranges.Value = '[100, 90, 110, 70]';
            app.speedsEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.speedsEditFieldLabel.Text = 'speeds';
            app.speeds = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.speeds.Value = '[10, 20, 15, 125]';
            app.DOATextAreaLabel = uilabel(app.ObjectconfigrationPanel);
            app.DOATextAreaLabel.Text = 'DOA';
            app.DOA = uitextarea(app.ObjectconfigrationPanel);
            app.DOA.Value = {'[0.1, 0.2, 0; 0.2, 0.1, 0; 0.3, 0.5, 0; 0.4, 0.2, 0]'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end